classdef DM_Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DMDataAnalyserLabel       matlab.ui.control.Label
        ProjectEditFieldLabel     matlab.ui.control.Label
        ProjectEditField          matlab.ui.control.EditField
        UISAVEButton              matlab.ui.control.Button
        EXITButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        Line1Panel                matlab.ui.container.Panel
        InputDropDownLabel        matlab.ui.control.Label
        InputDropDown             matlab.ui.control.DropDown
        FITTINGButton             matlab.ui.control.Button
        RefLBoundEditField_1      matlab.ui.control.NumericEditField
        RefHBoundEditField_1      matlab.ui.control.NumericEditField
        Label_1                   matlab.ui.control.Label
        DspLBoundEditField_1      matlab.ui.control.NumericEditField
        DspHBoundEditField_1      matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        RefRangeDropDownLabel     matlab.ui.control.Label
        RefRangeDropDown          matlab.ui.control.DropDown
        DisplayRangeLabel         matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        PLOTButton                matlab.ui.control.Button
        PointExtractionPanel      matlab.ui.container.Panel
        FINDINTERSECTIONButton    matlab.ui.control.Button
        InterPointEditField       matlab.ui.control.EditField
        FETCHButton               matlab.ui.control.Button
        TempFetchEditField        matlab.ui.control.EditField
        TempEditFieldLabel        matlab.ui.control.Label
        TempEditField             matlab.ui.control.NumericEditField
        RESETButton               matlab.ui.control.Button
        XLabelEditFieldLabel      matlab.ui.control.Label
        XLabelEditField           matlab.ui.control.EditField
        YLabelLeftEditFieldLabel  matlab.ui.control.Label
        YLabelLeftEditField       matlab.ui.control.EditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        RightEditFieldLabel       matlab.ui.control.Label
        RightEditField            matlab.ui.control.EditField
        Line2Panel                matlab.ui.container.Panel
        InputDropDown_2Label      matlab.ui.control.Label
        InputDropDown_2           matlab.ui.control.DropDown
        FITTINGButton_2           matlab.ui.control.Button
        RefLBoundEditField_2      matlab.ui.control.NumericEditField
        RefHBoundEditField_2      matlab.ui.control.NumericEditField
        Label_3                   matlab.ui.control.Label
        DspLBoundEditField_2      matlab.ui.control.NumericEditField
        DspHBoundEditField_2      matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        RefRangeDropDown_2Label   matlab.ui.control.Label
        RefRangeDropDown_2        matlab.ui.control.DropDown
        DisplayRangeLabel_2       matlab.ui.control.Label
        ResetButton_2             matlab.ui.control.Button
        PLOTButton_2              matlab.ui.control.Button
        YaxisLabel                matlab.ui.control.Label
        LRDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp % Main app object
        ProjectName % project name
        T % coefficient table
        yList % available y data sets
        xData % temperature set (K)
        yData
        p % 
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)


        function plotLine(app,LNum)
            ax = app.UIAxes;
            
            if isequal(LNum,1)
                yy = app.LRDropDown.Value;
                FTopt1 = app.RefRangeDropDown.Value;
                
                yyaxis(ax,yy);
                
                yDataType = app.InputDropDown.Value;
                yData = app.T{yDataType};
                app.yData{1} = yData;
                
                xData = app.xData;
                
                if isequal(FTopt1,'y')
                    app.RefLBoundEditField_1.Value = min(yData);
                    app.RefHBoundEditField_1.Value = max(yData);
                end
            else
                yy = app.LRDropdown.Value;
                FTopt1 = app.RefRangeDropDown_2.Value;
    
                yyaxis(ax,yy);
                
                yDataType = app.InputDropDown_2.Value;
                yData = app.T{yDataType};
                app.yData{LNum} = yData;
                
                xData = app.xData;
                
                if isequal(FTopt1,'y')
                    app.RefLBoundEditField_2.Value = min(yData);
                    app.RefHBoundEditField_2.Value = max(yData);
                end      
            end
          
            plot(ax,xData,yData,'o');
            hold(ax,"on")
            
        end
        
        function updateRange(app,xy,LNum)
            
            xData = app.xData;
            yData = app.yData{LNum};
            
            if isequal(LNum,1)
                if isequal(xy,'x')
                    app.RefLBoundEditField_1.Value = min(xData);
                    app.RefHBoundEditField_1.Value = max(xData);
                else
                    app.RefLBoundEditField_1.Value = min(yData);
                    app.RefHBoundEditField_1.Value = max(yData);
                end                
            else
                if isequal(value,'x')
                    app.RefLBoundEditField_2.Value = min(xData);
                    app.RefHBoundEditField_2.Value = max(xData);
                else
                    app.RefLBoundEditField_2.Value = min(yData);
                    app.RefHBoundEditField_2.Value = max(yData);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, yList, Values, tempList)
            %Store main app object
            app.CallingApp = mainapp;
            format short;
  
            app.T = Values;           
            yNum = size(yList,2);
            
            for idx=1:yNum
                yList{idx} = cell2mat(yList{idx});
            end
            
            app.yList = yList;
            
            ax = app.UIAxes;
            
            app.InputDropDown.Items = yList;
            app.InputDropDown.ItemsData = (1:yNum);
            app.InputDropDown_2.Items = yList;
            app.InputDropDown_2.ItemsData = (1:yNum);
            
            % available name format:
            % idxnumber: project file _ temperature K
            % ex) 1:alphaDisk_300K
            
            idxNum = size(Values{1},2);
            
            %{
            M1 = cell2mat(T.sampleID(1));
            projectName = M1(find(M1==':')+1 : find(M1=='_')-1);
            app.ProjectEditField.Value = projectName;
            %}

            labels= {};
            xData = [];
            
            for idx=1:idxNum
                Temp = tempList{idx};
                labels{idx} = Temp;
                xData = [xData str2num(strrep(Temp,'K',''))];
            end
            
            app.xData = xData;
            ax.XTick = xData;
            ax.XTickLabel = labels;
            ax.XTickLabelRotation= 45;
            
            app.DspLBoundEditField_1.Value = min(xData);
            app.DspHBoundEditField_1.Value = max(xData);
            app.RefLBoundEditField_1.Value = min(xData);
            app.RefHBoundEditField_1.Value = max(xData);
            
            app.DspLBoundEditField_2.Value = min(xData);
            app.DspHBoundEditField_2.Value = max(xData);
            app.RefLBoundEditField_2.Value = min(xData);
            app.RefHBoundEditField_2.Value = max(xData);
        end

        % Button pushed function: UISAVEButton
        function UISAVEButtonPushed(app, event)
            
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            plotLine(app,1);
        end

        % Button pushed function: FITTINGButton
        function FITTINGButtonPushed(app, event)
            LNum = 1;
            ax = app.UIAxes;
            
            xData = app.xData;
            yData = app.yData{LNum};
            
            FTopt1 = app.RefRangeDropDown.Value;
            
            xDataFit = xData;
            yDataFit = yData;
            
            lowB = app.RefLBoundEditField_1.Value;
            highB = app.RefHBoundEditField_1.Value;
            
            
            if isequal(FTopt1,'x')
                xDataFit = xData(sum(xData<lowB)+1:sum(xData<=highB));
                yDataFit = yData(sum(xData<lowB)+1:sum(xData<=highB));          
            else
                xDataFit = xData.*(yData>= lowB & yData <= highB);
                yDataFit = yData.*(yData>= lowB & yData <= highB);
                
                xDataFit = nonzeros(xDataFit)'
                yDataFit = nonzeros(yDataFit)'
            end
            
            p = polyfit(xDataFit,yDataFit,1);
            fitData = polyval(p,xData);
            app.p{LNum} = p;
            
            xlowB = app.DspLBoundEditField_1.Value;
            xhighB = app.DspHBoundEditField_1.Value;
            
            xDataFit = xData(sum(xData<xlowB)+1:sum(xData<=xhighB));
            fitData = fitData(sum(xData<xlowB)+1:sum(xData<=xhighB));
            hold(ax,"on")
            plot(ax,xDataFit,fitData);
        end

        % Value changed function: YLabelLeftEditField
        function YLabelLeftEditFieldValueChanged(app, event)
            value = app.YLabelLeftEditField.Value;
            ax = app.UIAxes;
            yyaxis(ax,"left");
            ylabel(ax, value);            
        end

        % Value changed function: XLabelEditField
        function XLabelEditFieldValueChanged(app, event)
            value = app.XLabelEditField.Value;
            ax = app.UIAxes;
            xlabel(ax, value);
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            ax = app.UIAxes;       
            title(ax, value);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            ax = app.UIAxes;
            yyaxis(ax, "right")
            cla(ax);
            yyaxis(ax,"left")
            cla(ax);
        end

        % Value changed function: RefRangeDropDown
        function RefRangeDropDownValueChanged(app, event)
            xy = app.RefRangeDropDown.Value;
            updateRange(app,xy,1);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            xData = app.xData;
            app.DspLBoundEditField_1.Value = min(xData);
            app.DspHBoundEditField_1.Value = max(xData);
        end

        % Value changed function: RightEditField
        function RightEditFieldValueChanged(app, event)
            value = app.RightEditField.Value;
            ax = app.UIAxes;
            yyaxis(ax,"right");
            ylabel(ax, value);
        end

        % Button pushed function: FITTINGButton_2
        function FITTINGButton_2Pushed(app, event)
            LNum = 2;
            ax = app.UIAxes;
            
            xData = app.xData;
            yData = app.yData{LNum};
            
            FTopt1 = app.RefRangeDropDown_2.Value;
            
            xDataFit = xData;
            yDataFit = yData;
            
            lowB = app.RefLBoundEditField_2.Value;
            highB = app.RefHBoundEditField_2.Value;
            
            
            if isequal(FTopt1,'x')
                xDataFit = xData(sum(xData<lowB)+1:sum(xData<highB));
                yDataFit = yData(sum(xData<lowB)+1:sum(xData<highB));          
            else
                xDataFit = xData.*(yData>= lowB & yData <= highB);
                yDataFit = yData.*(yData>= lowB & yData <= highB);
                
                xDataFit = nonzeros(xDataFit)'
                yDataFit = nonzeros(yDataFit)'
            end
            
            p = polyfit(xDataFit,yDataFit,1);
            fitData = polyval(p,xData);
            app.p{LNum} = p;
            
            xlowB = app.DspLBoundEditField_2.Value;
            xhighB = app.DspHBoundEditField_2.Value;
            
            xDataFit = xData(sum(xData<xlowB)+1:sum(xData<xhighB));
            fitData = fitData(sum(xData<xlowB)+1:sum(xData<xhighB));
            hold(ax,"on")
            plot(ax,xDataFit,fitData);            
        end

        % Value changed function: RefRangeDropDown_2
        function RefRangeDropDown_2ValueChanged(app, event)
            xy = app.RefRangeDropDown_2.Value;
            updateRange(app,xy,2);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            xData = app.xData;
            app.DspLBoundEditField_2.Value = min(xData);
            app.DspHBoundEditField_2.Value = max(xData);
        end

        % Value changed function: InputDropDown_2
        function InputDropDown_2ValueChanged(app, event)
            plotLine(app,2);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            plotLine(app,1);
        end

        % Button pushed function: PLOTButton_2
        function PLOTButton_2Pushed(app, event)
            plotLine(app,2);
        end

        % Button pushed function: FINDINTERSECTIONButton
        function FINDINTERSECTIONButtonPushed(app, event)
            xInter = roots(app.p{1} - app.p{2});
            yInter = polyval(app.p{1},xInter);
            app.InterPointEditField.Value = strcat('(',num2str(sprintf('%0.1f',xInter)),", ",num2str(sprintf('%0.2f',yInter)),')');
        end

        % Button pushed function: FETCHButton
        function FETCHButtonPushed(app, event)
            temp = app.TempEditField.Value;
            ax = app.UIAxes;
            tempResult = [];
            xline(ax,temp,'--r',{'Temperature (K)',(temp)});
            
            for idx = 1:size(app.p,2)
                t = polyval(app.p{idx},temp);
                tempResult = strcat(tempResult,' t',num2str(idx),'=',...
                    num2str(sprintf('%0.2f',t)));
            end
            
            app.TempFetchEditField.Value = tempResult;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DMDataAnalyserLabel = uilabel(app.UIFigure);
            app.DMDataAnalyserLabel.Text = 'DM Data Analyser';
            app.ProjectEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.UIFigure, 'text');
            app.UISAVEButton = uibutton(app.UIFigure, 'push');
            app.UISAVEButton.ButtonPushedFcn = createCallbackFcn(app, @UISAVEButtonPushed, true);
            app.UISAVEButton.Text = 'UI SAVE';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Line1Panel = uipanel(app.UIFigure);
            app.Line1Panel.Title = 'Line 1';
            app.InputDropDownLabel = uilabel(app.Line1Panel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Line1Panel);
            app.InputDropDown.Items = {'no data'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'no data';
            app.FITTINGButton = uibutton(app.Line1Panel, 'push');
            app.FITTINGButton.ButtonPushedFcn = createCallbackFcn(app, @FITTINGButtonPushed, true);
            app.FITTINGButton.Text = 'FITTING';
            app.RefLBoundEditField_1 = uieditfield(app.Line1Panel, 'numeric');
            app.RefLBoundEditField_1.ValueDisplayFormat = '%5.2f';
            app.RefHBoundEditField_1 = uieditfield(app.Line1Panel, 'numeric');
            app.RefHBoundEditField_1.ValueDisplayFormat = '%5.2f';
            app.Label_1 = uilabel(app.Line1Panel);
            app.Label_1.Text = '~';
            app.DspLBoundEditField_1 = uieditfield(app.Line1Panel, 'numeric');
            app.DspLBoundEditField_1.ValueDisplayFormat = '%5.2f';
            app.DspHBoundEditField_1 = uieditfield(app.Line1Panel, 'numeric');
            app.DspHBoundEditField_1.ValueDisplayFormat = '%5.2f';
            app.Label_2 = uilabel(app.Line1Panel);
            app.Label_2.Text = '~';
            app.RefRangeDropDownLabel = uilabel(app.Line1Panel);
            app.RefRangeDropDownLabel.Text = 'Ref. Range';
            app.RefRangeDropDown = uidropdown(app.Line1Panel);
            app.RefRangeDropDown.Items = {'x', 'y'};
            app.RefRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @RefRangeDropDownValueChanged, true);
            app.RefRangeDropDown.Value = 'x';
            app.DisplayRangeLabel = uilabel(app.Line1Panel);
            app.DisplayRangeLabel.Text = 'Display Range';
            app.ResetButton = uibutton(app.Line1Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PLOTButton = uibutton(app.Line1Panel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.PointExtractionPanel = uipanel(app.UIFigure);
            app.PointExtractionPanel.Title = 'Point Extraction';
            app.FINDINTERSECTIONButton = uibutton(app.PointExtractionPanel, 'push');
            app.FINDINTERSECTIONButton.ButtonPushedFcn = createCallbackFcn(app, @FINDINTERSECTIONButtonPushed, true);
            app.FINDINTERSECTIONButton.Text = 'FIND INTERSECTION';
            app.InterPointEditField = uieditfield(app.PointExtractionPanel, 'text');
            app.FETCHButton = uibutton(app.PointExtractionPanel, 'push');
            app.FETCHButton.ButtonPushedFcn = createCallbackFcn(app, @FETCHButtonPushed, true);
            app.FETCHButton.Text = 'FETCH';
            app.TempFetchEditField = uieditfield(app.PointExtractionPanel, 'text');
            app.TempEditFieldLabel = uilabel(app.PointExtractionPanel);
            app.TempEditFieldLabel.Text = 'Temp.';
            app.TempEditField = uieditfield(app.PointExtractionPanel, 'numeric');
            app.TempEditField.ValueDisplayFormat = '%.0f';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.XLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.UIFigure, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.YLabelLeftEditFieldLabel = uilabel(app.UIFigure);
            app.YLabelLeftEditFieldLabel.Text = 'Y Label (Left)';
            app.YLabelLeftEditField = uieditfield(app.UIFigure, 'text');
            app.YLabelLeftEditField.ValueChangedFcn = createCallbackFcn(app, @YLabelLeftEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.RightEditFieldLabel = uilabel(app.UIFigure);
            app.RightEditFieldLabel.Text = ' (Right)';
            app.RightEditField = uieditfield(app.UIFigure, 'text');
            app.RightEditField.ValueChangedFcn = createCallbackFcn(app, @RightEditFieldValueChanged, true);
            app.Line2Panel = uipanel(app.UIFigure);
            app.Line2Panel.Title = 'Line 2';
            app.InputDropDown_2Label = uilabel(app.Line2Panel);
            app.InputDropDown_2Label.Text = 'Input';
            app.InputDropDown_2 = uidropdown(app.Line2Panel);
            app.InputDropDown_2.Items = {'no data'};
            app.InputDropDown_2.ValueChangedFcn = createCallbackFcn(app, @InputDropDown_2ValueChanged, true);
            app.InputDropDown_2.Value = 'no data';
            app.FITTINGButton_2 = uibutton(app.Line2Panel, 'push');
            app.FITTINGButton_2.ButtonPushedFcn = createCallbackFcn(app, @FITTINGButton_2Pushed, true);
            app.FITTINGButton_2.Text = 'FITTING';
            app.RefLBoundEditField_2 = uieditfield(app.Line2Panel, 'numeric');
            app.RefLBoundEditField_2.ValueDisplayFormat = '%5.2f';
            app.RefHBoundEditField_2 = uieditfield(app.Line2Panel, 'numeric');
            app.RefHBoundEditField_2.ValueDisplayFormat = '%5.2f';
            app.Label_3 = uilabel(app.Line2Panel);
            app.Label_3.Text = '~';
            app.DspLBoundEditField_2 = uieditfield(app.Line2Panel, 'numeric');
            app.DspLBoundEditField_2.ValueDisplayFormat = '%5.2f';
            app.DspHBoundEditField_2 = uieditfield(app.Line2Panel, 'numeric');
            app.DspHBoundEditField_2.ValueDisplayFormat = '%5.2f';
            app.Label_4 = uilabel(app.Line2Panel);
            app.Label_4.Text = '~';
            app.RefRangeDropDown_2Label = uilabel(app.Line2Panel);
            app.RefRangeDropDown_2Label.Text = 'Ref. Range';
            app.RefRangeDropDown_2 = uidropdown(app.Line2Panel);
            app.RefRangeDropDown_2.Items = {'x', 'y'};
            app.RefRangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RefRangeDropDown_2ValueChanged, true);
            app.RefRangeDropDown_2.Value = 'x';
            app.DisplayRangeLabel_2 = uilabel(app.Line2Panel);
            app.DisplayRangeLabel_2.Text = 'Display Range';
            app.ResetButton_2 = uibutton(app.Line2Panel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.PLOTButton_2 = uibutton(app.Line2Panel, 'push');
            app.PLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLOTButton_2Pushed, true);
            app.PLOTButton_2.Text = 'PLOT';
            app.YaxisLabel = uilabel(app.UIFigure);
            app.YaxisLabel.Text = 'Y-axis';
            app.LRDropDown = uidropdown(app.UIFigure);
            app.LRDropDown.Items = {'left', 'right'};
            app.LRDropDown.Value = 'left';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DM_Tracker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end