classdef DR_Filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DYNAMICRANGEFILTERLabel         matlab.ui.control.Label
        CUTOFFSETTINGPanel              matlab.ui.container.Panel
        CutoffFrequencyLabel            matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        NoiseFloorEditFieldLabel        matlab.ui.control.Label
        NoiseFloorEditField             matlab.ui.control.NumericEditField
        CutoffFrequencyLabel_2          matlab.ui.control.Label
        CutoffFrequencySlider           matlab.ui.control.Slider
        SAMPLEINFORMATIONPanel          matlab.ui.container.Panel
        ThicknessmmEditFieldLabel       matlab.ui.control.Label
        ThicknessmmEditField            matlab.ui.control.NumericEditField
        FrequencyLimitTHzEditFieldLabel  matlab.ui.control.Label
        FrequencyLimitTHzEditField      matlab.ui.control.NumericEditField
        UpperlimitFrequencySliderLabel  matlab.ui.control.Label
        UpperlimitFrequencySlider       matlab.ui.control.Slider
        APPLYButton                     matlab.ui.control.Button
        APPLYALLButton                  matlab.ui.control.Button
        MeasurementTypeButtonGroup      matlab.ui.container.ButtonGroup
        TransmissionButton              matlab.ui.control.RadioButton
        ReflectionButton                matlab.ui.control.RadioButton
        SampleIDEditFieldLabel          matlab.ui.control.Label
        SampleIDEditField               matlab.ui.control.EditField
        STOPBANDTHzPanel                matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        BandStartEditField              matlab.ui.control.NumericEditField
        BandEndEditField                matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    
    properties (Access = public)
        refF
        refE
        samA
        n_eff
    end
    
    methods (Access = private)
        
        
        function findNF(app)
            % find the noise floor for the given reference data
            
            refF = app.refF;
            refE = app.refE;
            samA = app.samA;
            cutoffF = app.CutoffFrequencySlider.Value; % THz
            cutoffF = cutoffF * 10^12;
            
            cfLoc = find(refF>cutoffF,1);
            
            if isequal(app.ThicknessmmEditField.Value,0)
                    fig = app.UIFigure;
                    ID = app.SampleIDEditField.Value;
                    msg = strcat("[",(ID),"]"," doesn't have thickness information.");
                    uialert(fig,(msg),'Invalid Thickness');
                    return;
                end
            
            if isempty(cfLoc)
                cutoffF = refF(end);
                fig = app.UIFigure;
                maxF = num2str(cutoffF*10^-12,'%.2f');
                msg = strcat("The cutoff frquency data is not defined."," Max. Freq.:",(maxF));
                uialert(fig,(msg),'Invalid Data');
                return;
            end
            
%             noFl = mean(refE(cfLoc:end));
            noFl = refE(cfLoc);
            
            app.NoiseFloorEditField.Value = noFl;   
            
            plotAx1(app);
            plotAx2(app);
        end
        
        function plotAx1(app)
            ax = app.UIAxes1;
            noFl = app.NoiseFloorEditField.Value;
            cutoffF = app.CutoffFrequencyEditField.Value;
            
            refF = app.refF.* 10^-12;
            cutoffF = cutoffF;
            refE = app.refE./noFl;
            
            plot(ax,refF,refE,"LineWidth",1);
            hold(ax,"on")
            xline(ax,cutoffF,'--r',{'Cutoff Frequency',(cutoffF)});
            yline(ax,1,'--b',{'Noise Floor'});
            hold(ax,"off")
            
        end
        
        function plotAx2(app)
            samA = app.samA;
            limitFreq = app.FrequencyLimitTHzEditField.Value;
            noFl = app.NoiseFloorEditField.Value;
            thickness = app.ThicknessmmEditField.Value;
            bandStart = app.BandStartEditField.Value;
            bandEnd = app.BandEndEditField.Value;

            refF = app.refF.*10^-12;
            refE = app.refE./noFl;


            c = 299792458; % the speed of light
            n_medium = 1; % refractive index of medium
            n_reference = 1; % refractive indx of the reference
            n_sample = app.n_eff;
                
            % calcute the absorption coefficient, log base is 10.
            % equation 2 from Jepsen and Fisher
            ref_factor = 4.*n_medium.*n_reference./((n_medium + n_reference).^2);
            sam_factor = 4.*n_medium.*n_sample./((n_medium + n_sample).^2);
            scaleFactor = ref_factor./sam_factor;
                
            scaledRefDRMag = refE.*scaleFactor;
                
            DRlimit = 2./(thickness*0.1).*log(scaledRefDRMag);
            

            ax = app.UIAxes2;
            cla(ax)
            hold(ax,"on")
            
            stopBand = app.Switch.Value;
            
            if isequal(stopBand,'On')
                xR = [bandStart bandEnd bandEnd bandStart];
                patch(ax,xR,[-100 -100 500 500],'r','FaceAlpha',0.2,'EdgeColor','none');
            end
            
            plot(ax,refF,DRlimit,'-','LineWidth',1);
            plot(ax,refF,samA,'LineWidth',1);
            xline(ax,limitFreq,'--r',{'Upper-limit Frequency',(limitFreq)});
            hold(ax,"off")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, refF, refE, samA, n_eff, thickness, stopBand, ID)
            
            %Store main app object
            app.CallingApp = mainapp;
            app.refF = refF;
            app.refE = refE;
            app.samA = samA;
            app.n_eff = n_eff;
            cutoffF = app.CutoffFrequencySlider.Value;
            limitFreq = app.UpperlimitFrequencySlider.Value;
            
            if length(stopBand) == 2
                app.BandStartEditField.Value = stopBand(1);
                app.BandEndEditField.Value = stopBand(2);
                app.Switch.Value = "On";
            end
            
            app.ThicknessmmEditField.Value = thickness;
            app.CutoffFrequencyEditField.Value = cutoffF;            
            app.SampleIDEditField.Value = ID;
            app.FrequencyLimitTHzEditField.Value = limitFreq;
            
            % check measurement tyep: transmission or reflecton
            measType = app.MeasurementTypeButtonGroup.SelectedObject.Text;
            
            if isequal(measType,'Reflection')
                % put logics and equations for reflection measurement                                
            end
            
            findNF(app);           

        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            % Call main app's public function
            limitFreq = app.FrequencyLimitTHzEditField.Value;
             
            if isequal(app.Switch.Value,'On')
                stopBand = [app.BandStartEditField.Value app.BandEndEditField.Value];
            else
                stopBand = 0;
            end
            
            updateFreqRange(app.CallingApp,limitFreq,0,stopBand);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: APPLYALLButton
        function APPLYALLButtonPushed(app, event)
            % Call main app's public function
            limitFreq = app.FrequencyLimitTHzEditField.Value;
            
            if isequal(app.Switch.Value,'On')
                stopBand = [app.BandStartEditField.Value app.BandEndEditField.Value];
            else
                stopBand = 0;
            end
            
            updateFreqRange(app.CallingApp,limitFreq,1,stopBand);
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: CutoffFrequencySlider
        function CutoffFrequencySliderValueChanged(app, event)
            value = app.CutoffFrequencySlider.Value;
            app.CutoffFrequencyEditField.Value = value;
            findNF(app);
            plotAx1(app);
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            value = app.CutoffFrequencyEditField.Value;
            app.CutoffFrequencySlider.Value = value;
            findNF(app);
            plotAx1(app);
        end

        % Value changed function: UpperlimitFrequencySlider
        function UpperlimitFrequencySliderValueChanged(app, event)
            value = app.UpperlimitFrequencySlider.Value;
            app.FrequencyLimitTHzEditField.Value = value;
            plotAx2(app);
        end

        % Value changed function: FrequencyLimitTHzEditField
        function FrequencyLimitTHzEditFieldValueChanged(app, event)
            value = app.FrequencyLimitTHzEditField.Value;
            app.UpperlimitFrequencySlider.Value = value;
            plotAx2(app);            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                app.BandStartEditField.Enable = true;
                app.BandEndEditField.Enable = true;
                app.BandStartEditField.Editable = true;
                app.BandEndEditField.Editable = true;
            else
                app.BandStartEditField.Enable = false;
                app.BandEndEditField.Enable = false;
            end
            plotAx2(app);
        end

        % Value changed function: BandStartEditField
        function BandStartEditFieldValueChanged(app, event)
            plotAx2(app);
        end

        % Value changed function: BandEndEditField
        function BandEndEditFieldValueChanged(app, event)
            plotAx2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DYNAMICRANGEFILTERLabel = uilabel(app.UIFigure);
            app.DYNAMICRANGEFILTERLabel.Tooltip = {'Jepsen et al. 2005: Dynamic range in terahertz time-domain transmission and reflection spectroscopy'};
            app.DYNAMICRANGEFILTERLabel.Text = 'DYNAMIC RANGE FILTER';
            app.CUTOFFSETTINGPanel = uipanel(app.UIFigure);
            app.CUTOFFSETTINGPanel.Title = 'CUTOFF SETTING';
            app.CutoffFrequencyLabel = uilabel(app.CUTOFFSETTINGPanel);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.CUTOFFSETTINGPanel, 'numeric');
            app.CutoffFrequencyEditField.Limits = [0 5];
            app.CutoffFrequencyEditField.ValueDisplayFormat = '%.2f';
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.NoiseFloorEditFieldLabel = uilabel(app.CUTOFFSETTINGPanel);
            app.NoiseFloorEditFieldLabel.Text = 'Noise Floor';
            app.NoiseFloorEditField = uieditfield(app.CUTOFFSETTINGPanel, 'numeric');
            app.NoiseFloorEditField.Editable = 'off';
            app.CutoffFrequencyLabel_2 = uilabel(app.CUTOFFSETTINGPanel);
            app.CutoffFrequencyLabel_2.Text = 'Cutoff Frequency';
            app.CutoffFrequencySlider = uislider(app.CUTOFFSETTINGPanel);
            app.CutoffFrequencySlider.Limits = [1 5];
            app.CutoffFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.CutoffFrequencySlider.Value = 3.5;
            app.SAMPLEINFORMATIONPanel = uipanel(app.UIFigure);
            app.SAMPLEINFORMATIONPanel.Title = 'SAMPLE INFORMATION';
            app.ThicknessmmEditFieldLabel = uilabel(app.SAMPLEINFORMATIONPanel);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.ThicknessmmEditField = uieditfield(app.SAMPLEINFORMATIONPanel, 'numeric');
            app.ThicknessmmEditField.ValueDisplayFormat = '%.3f';
            app.FrequencyLimitTHzEditFieldLabel = uilabel(app.SAMPLEINFORMATIONPanel);
            app.FrequencyLimitTHzEditFieldLabel.Text = 'Frequency Limit (THz)';
            app.FrequencyLimitTHzEditField = uieditfield(app.SAMPLEINFORMATIONPanel, 'numeric');
            app.FrequencyLimitTHzEditField.Limits = [0 4];
            app.FrequencyLimitTHzEditField.ValueDisplayFormat = '%.3f';
            app.FrequencyLimitTHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyLimitTHzEditFieldValueChanged, true);
            app.UpperlimitFrequencySliderLabel = uilabel(app.SAMPLEINFORMATIONPanel);
            app.UpperlimitFrequencySliderLabel.Text = 'Upper-limit Frequency';
            app.UpperlimitFrequencySlider = uislider(app.SAMPLEINFORMATIONPanel);
            app.UpperlimitFrequencySlider.Limits = [1 4];
            app.UpperlimitFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @UpperlimitFrequencySliderValueChanged, true);
            app.UpperlimitFrequencySlider.Value = 3.5;
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.APPLYALLButton = uibutton(app.UIFigure, 'push');
            app.APPLYALLButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYALLButtonPushed, true);
            app.APPLYALLButton.Text = 'APPLY ALL';
            app.MeasurementTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.MeasurementTypeButtonGroup.Title = 'Measurement Type';
            app.TransmissionButton = uiradiobutton(app.MeasurementTypeButtonGroup);
            app.TransmissionButton.Text = 'Transmission';
            app.TransmissionButton.Value = true;
            app.ReflectionButton = uiradiobutton(app.MeasurementTypeButtonGroup);
            app.ReflectionButton.Enable = 'off';
            app.ReflectionButton.Text = 'Reflection';
            app.SampleIDEditFieldLabel = uilabel(app.UIFigure);
            app.SampleIDEditFieldLabel.Text = 'Sample ID';
            app.SampleIDEditField = uieditfield(app.UIFigure, 'text');
            app.STOPBANDTHzPanel = uipanel(app.UIFigure);
            app.STOPBANDTHzPanel.Title = 'STOP BAND (THz)';
            app.Switch = uiswitch(app.STOPBANDTHzPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.BandStartEditField = uieditfield(app.STOPBANDTHzPanel, 'numeric');
            app.BandStartEditField.Limits = [1 4];
            app.BandStartEditField.ValueChangedFcn = createCallbackFcn(app, @BandStartEditFieldValueChanged, true);
            app.BandStartEditField.Editable = 'off';
            app.BandStartEditField.Value = 2.5;
            app.BandEndEditField = uieditfield(app.STOPBANDTHzPanel, 'numeric');
            app.BandEndEditField.Limits = [1 4];
            app.BandEndEditField.ValueChangedFcn = createCallbackFcn(app, @BandEndEditFieldValueChanged, true);
            app.BandEndEditField.Editable = 'off';
            app.BandEndEditField.Value = 3;
            app.toEditFieldLabel = uilabel(app.STOPBANDTHzPanel);
            app.toEditFieldLabel.Text = 'to';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Normalized Dynamic Range and Noise Floor')
            xlabel(app.UIAxes1, 'Frequency (THz)')
            ylabel(app.UIAxes1, 'Dynamic Range')
            app.UIAxes1.XLim = [0 5];
            app.UIAxes1.YLim = [0 3000];
            app.UIAxes1.YScale = 'log';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Absorption Spectrum')
            xlabel(app.UIAxes2, 'Frequency (THz)')
            ylabel(app.UIAxes2, 'Absorption coefficient [cm^{-1}]')
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [-100 500];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DR_Filter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end