classdef T_Finder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SEARCHButton                   matlab.ui.control.Button
        INSITUSAMPLETHICKNESSFINDERLabel  matlab.ui.control.Label
        MULTIPLEREFLECTIONTIMEpsPanel  matlab.ui.container.Panel
        delta_tEditFieldLabel          matlab.ui.control.Label
        delta_tEditField               matlab.ui.control.NumericEditField
        stReflectionPeakEditField      matlab.ui.control.NumericEditField
        stPeakLabel                    matlab.ui.control.Label
        PeakSpinner                    matlab.ui.control.Spinner
        CorrectedPeakTimeEditFieldLabel  matlab.ui.control.Label
        CorrectedPeakTimeEditField     matlab.ui.control.NumericEditField
        SAMPLEINFORMATIONPanel         matlab.ui.container.Panel
        ThicknessmmEditFieldLabel      matlab.ui.control.Label
        ThicknessmmEditField           matlab.ui.control.NumericEditField
        RefractiveIndexEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexEditField       matlab.ui.control.NumericEditField
        APPLYButton                    matlab.ui.control.Button
        MinPeakProminenceSliderLabel   matlab.ui.control.Label
        MinPeakProminenceSlider        matlab.ui.control.Slider
        MinPkPromEdit                  matlab.ui.control.NumericEditField
        APPLYALLALGORITHMButton        matlab.ui.control.Button
        REFERENCEMEDIUMButtonGroup     matlab.ui.container.ButtonGroup
        AIRButton                      matlab.ui.control.RadioButton
        QUARTZButton                   matlab.ui.control.RadioButton
        PEButton                       matlab.ui.control.RadioButton
        APPLYALLTHICKNESSButton        matlab.ui.control.Button
        STATUSEditFieldLabel           matlab.ui.control.Label
        STATUSEditField                matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    
    properties (Access = public)
        refT
        refE
        samT
        samE
        diff
        pksDetail
        locs % Peak locations (ps time)
        locNum % Chosen Peak number
        maxPer % valid range start from maxPer and higher (0.5 - 1)
    end
    
    methods (Access = private)
        
        function Calc(app,mrt)
            % peak time correction function
            loc = find(app.refT==mrt,1);
            maxPer = 0.70;
            app.maxPer = maxPer;
            
            mrtAmp = app.diff(loc);
            mrtRange = app.diff > mrtAmp*maxPer;
            maxPerRange = 25;
            mrtRange(1:loc-maxPerRange) = 0;
            mrtRange(loc+maxPerRange:end) = 0;
            mrt = sum(app.refT.*mrtRange)/sum(mrtRange);
            app.CorrectedPeakTimeEditField.Value = mrt;
            
            del_t = app.pksDetail(3);
            mrt = mrt*10^-12;
            del_t = del_t*10^-12;
            
            refMdm = app.REFERENCEMEDIUMButtonGroup.SelectedObject.Text;
            if isequal(refMdm,'QUARTZ')
                % Quartz 1st internal reflection time: 2*2*2mm*2.11/c
                mrt = mrt -5.6267e-11;
            end
            
            thickness = (mrt - 3*del_t)*3e8/2;
            n_eff = del_t*3e8/(thickness) + 1;
            app.ThicknessmmEditField.Value = thickness*10^3;
            app.RefractiveIndexEditField.Value = n_eff;     
        end
        
        function findPeaks(app,pksProm)
            refT = app.refT;
            refE = app.refE;
            samT = app.samT;
            samE = app.samE;
            pksDetail = app.pksDetail;
            del_t = app.pksDetail(3);
            
            
            tShift = find(refT > del_t,1) - find(refT > 0,1);
            refE = [zeros(1,tShift) refE(1:end-tShift)];
            refE = refE * (pksDetail(2)/pksDetail(1));

            app.delta_tEditField.Value = del_t;           
            refMdm = app.REFERENCEMEDIUMButtonGroup.SelectedObject.Text;
            
            % define the lower limit of peaks
            % 4* del_ t is approximation of '2d/c + 3del_t'
            switch refMdm
                case 'AIR'
                    pksLLimit = 4*del_t;
                case 'QUARTZ'
                    pksLLimit = 4*del_t + 5.6267e-11;
                case 'PE'
                    % edit here for PE case 
            end       
            
            diff = samE - refE;
            app.diff = diff;
            [pks, locs] = findpeaks(diff,refT,'MinPeakProminence',pksProm);
            pks = pks(locs>pksLLimit);
            locs = locs(locs>pksLLimit);
            
            if ~isempty(pks)
                spLim = length(pks);
            else
                spLim = 1;
                locs = 0;
            end

            app.locs = locs;
            
            if spLim > 1
                app.PeakSpinner.Visible = "on";
                app.PeakSpinner.Limits(2) = spLim;
                mrt = locs(1);
            else
                app.PeakSpinner.Visible = "off";
                mrt = locs;
            end
            
            app.locNum = 1;
            ax = app.UIAxes;
            plot(ax,refT,refE+1,'--',samT,samE+0.5,'--');
            hold(ax,"on")
            plot(ax,refT,diff,'linewidth',1);
            plot(ax,locs,pks,'o');
            text(ax,locs+.1,pks,num2str((1:numel(pks))'));
            hold(ax,"off")

            legend(ax,'Reference','Sample','Difference','peaks')   
            
            % calculate and diplay EditFields
            Calc(app,mrt);
            app.stReflectionPeakEditField.Value = mrt;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, refT, refE, samT, samE, pksDetail, ID)
            %Store main app object
            app.CallingApp = mainapp;
            app.refT = refT;
            app.refE = refE;
            app.samT = samT;
            app.samE = samE;
            app.pksDetail = pksDetail; % [REf_max Sam_max delta_t, ...]
            app.STATUSEditField.Value = ID;
            
            % Check the waveform have Quartz window internal reflection information
            if samT(end)< 56
                app.STATUSEditField.Value = 'No internal reflection peaks of quartz windows in the waveforms'
                app.QUARTZButton.Enable = "off";
                                
            end
            
            %Press TDindex and delta_t inputs
            pksProm = app.MinPeakProminenceSlider.Value;
            app.MinPkPromEdit.Value = pksProm;
            
            findPeaks(app,pksProm);            
        end

        % Button pushed function: SEARCHButton
        function SEARCHButtonPushed(app, event)
            pksProm = app.MinPeakProminenceSlider.Value;
            findPeaks(app,pksProm);            
        end

        % Value changing function: MinPeakProminenceSlider
        function MinPeakProminenceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MinPkPromEdit.Value = changingValue;
        end

        % Value changed function: PeakSpinner
        function PeakSpinnerValueChanged(app, event)
            value = app.PeakSpinner.Value;
            app.locNum = value;
            mrt = app.locs(value);
            app.stReflectionPeakEditField.Value = mrt;
            Calc(app,mrt)
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            
            % Call main app's public function
            thickness = app.ThicknessmmEditField.Value;
            
            if thickness < 0
                fig = app.UIFigure;
                uialert(fig,'Please recalcuate the thickness', 'Invalid Thickness');
                return;
            end
            
            pksProm = app.MinPeakProminenceSlider.Value;
            loc = app.locNum;
            refMdm = app.REFERENCEMEDIUMButtonGroup.SelectedObject.Text;
            maxPer = app.maxPer;
            updateThickness(app.CallingApp,thickness,pksProm,loc,0,refMdm,maxPer);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: APPLYALLALGORITHMButton
        function APPLYALLALGORITHMButtonPushed(app, event)
            % Call main app's public function
            thickness = app.ThicknessmmEditField.Value;
            
            if thickness < 0
                fig = app.UIFigure;
                uialert(fig,'Please recalcuate the thickness', 'Invalid Thickness');
                return;
            end
            
            pksProm = app.MinPeakProminenceSlider.Value;
            loc = app.locNum;
            refMdm = app.REFERENCEMEDIUMButtonGroup.SelectedObject.Text;
            maxPer = app.maxPer;
            updateThickness(app.CallingApp,thickness,pksProm,loc,1,refMdm,maxPer);
            
            % Delete the dialog box
            delete(app)
        end

        % Selection changed function: REFERENCEMEDIUMButtonGroup
        function REFERENCEMEDIUMButtonGroupSelectionChanged(app, event)
            selectedButton = app.REFERENCEMEDIUMButtonGroup.SelectedObject;
            mrt = app.stReflectionPeakEditField.Value;
            Calc(app,mrt)
        end

        % Button pushed function: APPLYALLTHICKNESSButton
        function APPLYALLTHICKNESSButtonPushed(app, event)
            % Call main app's public function
            thickness = app.ThicknessmmEditField.Value;
            
            if thickness < 0
                fig = app.UIFigure;
                uialert(fig,'Please recalcuate the thickness', 'Invalid Thickness');
                return;
            end
            
            pksProm = app.MinPeakProminenceSlider.Value;
            loc = app.locNum;
            refMdm = app.REFERENCEMEDIUMButtonGroup.SelectedObject.Text;
            maxPer = app.maxPer;
            updateThickness(app.CallingApp,thickness,pksProm,loc,2,refMdm,maxPer);
            
            % Delete the dialog box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SEARCHButton = uibutton(app.UIFigure, 'push');
            app.SEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @SEARCHButtonPushed, true);
            app.SEARCHButton.Text = 'SEARCH';
            app.INSITUSAMPLETHICKNESSFINDERLabel = uilabel(app.UIFigure);
            app.INSITUSAMPLETHICKNESSFINDERLabel.Text = 'IN-SITU SAMPLE THICKNESS FINDER';
            app.MULTIPLEREFLECTIONTIMEpsPanel = uipanel(app.UIFigure);
            app.MULTIPLEREFLECTIONTIMEpsPanel.Title = 'MULTIPLE REFLECTION TIME (ps)';
            app.delta_tEditFieldLabel = uilabel(app.MULTIPLEREFLECTIONTIMEpsPanel);
            app.delta_tEditFieldLabel.Text = 'delta_t';
            app.delta_tEditField = uieditfield(app.MULTIPLEREFLECTIONTIMEpsPanel, 'numeric');
            app.delta_tEditField.Editable = 'off';
            app.stReflectionPeakEditField = uieditfield(app.MULTIPLEREFLECTIONTIMEpsPanel, 'numeric');
            app.stReflectionPeakEditField.Editable = 'off';
            app.stPeakLabel = uilabel(app.MULTIPLEREFLECTIONTIMEpsPanel);
            app.stPeakLabel.Text = '1st Peak';
            app.PeakSpinner = uispinner(app.MULTIPLEREFLECTIONTIMEpsPanel);
            app.PeakSpinner.Limits = [1 5];
            app.PeakSpinner.ValueDisplayFormat = '%.0f';
            app.PeakSpinner.ValueChangedFcn = createCallbackFcn(app, @PeakSpinnerValueChanged, true);
            app.PeakSpinner.Editable = 'off';
            app.PeakSpinner.Value = 1;
            app.CorrectedPeakTimeEditFieldLabel = uilabel(app.MULTIPLEREFLECTIONTIMEpsPanel);
            app.CorrectedPeakTimeEditFieldLabel.Text = 'Corrected Peak Time';
            app.CorrectedPeakTimeEditField = uieditfield(app.MULTIPLEREFLECTIONTIMEpsPanel, 'numeric');
            app.CorrectedPeakTimeEditField.Editable = 'off';
            app.SAMPLEINFORMATIONPanel = uipanel(app.UIFigure);
            app.SAMPLEINFORMATIONPanel.Title = 'SAMPLE INFORMATION';
            app.ThicknessmmEditFieldLabel = uilabel(app.SAMPLEINFORMATIONPanel);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness (mm)';
            app.ThicknessmmEditField = uieditfield(app.SAMPLEINFORMATIONPanel, 'numeric');
            app.ThicknessmmEditField.ValueDisplayFormat = '%.3f';
            app.RefractiveIndexEditFieldLabel = uilabel(app.SAMPLEINFORMATIONPanel);
            app.RefractiveIndexEditFieldLabel.Text = 'Refractive Index';
            app.RefractiveIndexEditField = uieditfield(app.SAMPLEINFORMATIONPanel, 'numeric');
            app.RefractiveIndexEditField.ValueDisplayFormat = '%.3f';
            app.RefractiveIndexEditField.Editable = 'off';
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.MinPeakProminenceSliderLabel = uilabel(app.UIFigure);
            app.MinPeakProminenceSliderLabel.Text = 'Min. Peak Prominence';
            app.MinPeakProminenceSlider = uislider(app.UIFigure);
            app.MinPeakProminenceSlider.Limits = [0 0.2];
            app.MinPeakProminenceSlider.ValueChangingFcn = createCallbackFcn(app, @MinPeakProminenceSliderValueChanging, true);
            app.MinPeakProminenceSlider.Value = 0.13;
            app.MinPkPromEdit = uieditfield(app.UIFigure, 'numeric');
            app.MinPkPromEdit.Editable = 'off';
            app.MinPkPromEdit.Value = 0.13;
            app.APPLYALLALGORITHMButton = uibutton(app.UIFigure, 'push');
            app.APPLYALLALGORITHMButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYALLALGORITHMButtonPushed, true);
            app.APPLYALLALGORITHMButton.Text = 'APPLY ALL (ALGORITHM)';
            app.REFERENCEMEDIUMButtonGroup = uibuttongroup(app.UIFigure);
            app.REFERENCEMEDIUMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @REFERENCEMEDIUMButtonGroupSelectionChanged, true);
            app.REFERENCEMEDIUMButtonGroup.Title = 'REFERENCE MEDIUM';
            app.AIRButton = uiradiobutton(app.REFERENCEMEDIUMButtonGroup);
            app.AIRButton.Text = 'AIR';
            app.AIRButton.Value = true;
            app.QUARTZButton = uiradiobutton(app.REFERENCEMEDIUMButtonGroup);
            app.QUARTZButton.Tooltip = {'Current Window Specification:T 2mm,  N 2.11'};
            app.QUARTZButton.Text = 'QUARTZ';
            app.PEButton = uiradiobutton(app.REFERENCEMEDIUMButtonGroup);
            app.PEButton.Enable = 'off';
            app.PEButton.Tooltip = {'under construction'};
            app.PEButton.Text = 'PE';
            app.APPLYALLTHICKNESSButton = uibutton(app.UIFigure, 'push');
            app.APPLYALLTHICKNESSButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYALLTHICKNESSButtonPushed, true);
            app.APPLYALLTHICKNESSButton.Text = 'APPLY ALL (THICKNESS)';
            app.STATUSEditFieldLabel = uilabel(app.UIFigure);
            app.STATUSEditFieldLabel.Text = 'STATUS';
            app.STATUSEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (ps)')
            ylabel(app.UIAxes, 'E field (a.u.)')
            app.UIAxes.XLim = [-2 18];
            app.UIAxes.YLim = [-2 7];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_Finder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end