classdef T_Fitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SAMPLETHICKNESSFITTINGLabel    matlab.ui.control.Label
        MeasurementCaseEditFieldLabel  matlab.ui.control.Label
        MeasurementCaseEditField       matlab.ui.control.EditField
        FittingReferenceDataEditFieldLabel  matlab.ui.control.Label
        FittingReferenceDataEditField  matlab.ui.control.EditField
        LINEARFITTINGButton            matlab.ui.control.Button
        DatatobeappliedEditFieldLabel  matlab.ui.control.Label
        DatatobeappliedEditField       matlab.ui.control.EditField
        APPLYButton                    matlab.ui.control.Button
        FitlineGradientmmKEditFieldLabel  matlab.ui.control.Label
        FitlineGradientmmKEditField    matlab.ui.control.NumericEditField
        NumofCasesEditFieldLabel       matlab.ui.control.Label
        NumofCasesEditField            matlab.ui.control.NumericEditField
        TemperatureSettingKEditFieldLabel  matlab.ui.control.Label
        TemperatureSettingKEditField   matlab.ui.control.EditField
        EXITButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main app object
        sampleNames % sample names
        thicknesses % sample thickness
        fitData % fitted thickness
        updateList % list to be updated
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleNames, thicknesses, analysisData, fileName)
            %Store main app object
            app.CallingApp = mainapp;
            app.sampleNames = sampleNames;
            app.thicknesses = thicknesses;
            ax = app.UIAxes;
            c = 3e8; % the speed of light in vacuum
            tErr = []; % thickness error range
                        
            title(ax, 'SAMPLE THICKNESS')
            xlabel(ax, 'SAMPLE LIST')
            ylabel(ax, 'THICKNESS (mm)')

            labels= {};
            tList = [];
            idxNum = length(thicknesses);
            app.NumofCasesEditField.Value = idxNum;
            app.FittingReferenceDataEditField.Value = mat2str(1:idxNum);
            app.DatatobeappliedEditField.Value = mat2str(1:idxNum);
            app.MeasurementCaseEditField.Value = fileName;

            
            for idx=1:idxNum
                labels{idx} = strrep(strjoin(sampleNames{idx}),'_',' ');
                tList =[tList thicknesses{idx}];
                rIdx = analysisData{idx}(3);
                eRange = 4*0.02*c/rIdx*10^-10; % mm unit
                tErr = [tErr eRange]; 
            end
            
            ax.XTick = (1:idxNum);
            ax.XTickLabel = labels;
            ax.XTickLabelRotation= 45;
            err = tErr
            mean(err)
            errorbar(ax,(1:idxNum),tList,err,'o');          
        end

        % Button pushed function: LINEARFITTINGButton
        function LINEARFITTINGButtonPushed(app, event)
            %
            ax = app.UIAxes;
            refData = str2num(app.FittingReferenceDataEditField.Value);
            xData = str2num(app.TemperatureSettingKEditField.Value);
            yData = cell2mat(app.thicknesses(refData));
            
            if ~isequal(length(xData),length(yData))
                fig = app.UIFigure;
                uialert(fig,'the number of x-axis samples and temperature range are not matched', 'Warning');
                return;
            end
            
            p = polyfit(xData,yData,1);
            fitData = polyval(p,xData);
            hold(ax,"on")
            plot(ax,refData,fitData);
            hold(ax,"off")
            thrEx = p(1) * 10^-3;
            app.FitlineGradientmmKEditField.Value = thrEx;
            
            app.fitData = fitData;
            app.updateList = refData;
        end

        % Value changed function: FittingReferenceDataEditField
        function FittingReferenceDataEditFieldValueChanged(app, event)
            value = app.FittingReferenceDataEditField.Value;
            app.DatatobeappliedEditField.Value = value;
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            % Call main app's public function
            thicknesses = app.fitData;
            updateList = app.updateList;
            
            if isempty(thicknesses)
                fig = app.UIFigure;
                uialert(fig,'No available fitting data', 'Warning');
                return;
            end
            
            updateThickness2(app.CallingApp,thicknesses,updateList);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SAMPLETHICKNESSFITTINGLabel = uilabel(app.UIFigure);
            app.SAMPLETHICKNESSFITTINGLabel.Text = 'SAMPLE THICKNESS FITTING';
            app.MeasurementCaseEditFieldLabel = uilabel(app.UIFigure);
            app.MeasurementCaseEditFieldLabel.Text = 'Measurement Case';
            app.MeasurementCaseEditField = uieditfield(app.UIFigure, 'text');
            app.FittingReferenceDataEditFieldLabel = uilabel(app.UIFigure);
            app.FittingReferenceDataEditFieldLabel.Text = 'Fitting Reference Data';
            app.FittingReferenceDataEditField = uieditfield(app.UIFigure, 'text');
            app.FittingReferenceDataEditField.ValueChangedFcn = createCallbackFcn(app, @FittingReferenceDataEditFieldValueChanged, true);
            app.LINEARFITTINGButton = uibutton(app.UIFigure, 'push');
            app.LINEARFITTINGButton.ButtonPushedFcn = createCallbackFcn(app, @LINEARFITTINGButtonPushed, true);
            app.LINEARFITTINGButton.Text = 'LINEAR FITTING';
            app.DatatobeappliedEditFieldLabel = uilabel(app.UIFigure);
            app.DatatobeappliedEditFieldLabel.Text = 'Data to be applied';
            app.DatatobeappliedEditField = uieditfield(app.UIFigure, 'text');
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.FitlineGradientmmKEditFieldLabel = uilabel(app.UIFigure);
            app.FitlineGradientmmKEditFieldLabel.Text = 'Fit-line Gradient (mm/K)';
            app.FitlineGradientmmKEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofCasesEditFieldLabel = uilabel(app.UIFigure);
            app.NumofCasesEditFieldLabel.Text = 'Num. of Cases';
            app.NumofCasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureSettingKEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureSettingKEditFieldLabel.Text = 'Temperature Setting (K)';
            app.TemperatureSettingKEditField = uieditfield(app.UIFigure, 'text');
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Thickness (mm)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_Fitter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end